classdef sleep_ana < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        LoadEEGButton          matlab.ui.control.Button
        sLabel                 matlab.ui.control.Label
        StarttSliderLabel      matlab.ui.control.Label
        StarttSlider           matlab.ui.control.Slider
        fsEditFieldLabel       matlab.ui.control.Label
        SpanEditFieldLabel     matlab.ui.control.Label
        SpanEditField          matlab.ui.control.NumericEditField
        HzLabel                matlab.ui.control.Label
        fsEditField            matlab.ui.control.NumericEditField
        tEditFieldLabel        matlab.ui.control.Label
        tEditField             matlab.ui.control.NumericEditField
        LoadEMGButton          matlab.ui.control.Button
        FullViewButton         matlab.ui.control.Button
        EEGSpectrogramButton   matlab.ui.control.Button
        WinLenEditFieldLabel   matlab.ui.control.Label
        WinLenEditField        matlab.ui.control.NumericEditField
        sLabel_3               matlab.ui.control.Label
        OverlapEditFieldLabel  matlab.ui.control.Label
        OverlapEditField       matlab.ui.control.NumericEditField
        sLabel_4               matlab.ui.control.Label
        sLabel_2               matlab.ui.control.Label
        SelROICheckBox         matlab.ui.control.CheckBox
        EpochEditFieldLabel    matlab.ui.control.Label
        EpochEditField         matlab.ui.control.NumericEditField
        sLabel_5               matlab.ui.control.Label
        AutoScoreButton        matlab.ui.control.Button
        ManualThresholdButton  matlab.ui.control.Button
        nremLabel              matlab.ui.control.Label
        remLabel               matlab.ui.control.Label
        wakeLabel              matlab.ui.control.Label
        EditField              matlab.ui.control.NumericEditField
        EEGUIAxes              matlab.ui.control.UIAxes
        EMGUIAxes              matlab.ui.control.UIAxes
        spectrogramUIAxes      matlab.ui.control.UIAxes
        deltaUIAxes            matlab.ui.control.UIAxes
        thetaratioUIAxes       matlab.ui.control.UIAxes
        EMGpowerUIAxes         matlab.ui.control.UIAxes
        DeltaThetaUIAxes       matlab.ui.control.UIAxes
        ThetaDistUIAxes        matlab.ui.control.UIAxes
        DeltaDistUIAxes        matlab.ui.control.UIAxes
        EMGThetaUIAxes         matlab.ui.control.UIAxes
        ThetaDistUIAxes2       matlab.ui.control.UIAxes
        EMGpowerUIAxes2        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        path = pwd;
        EEG;
        EMG;
        maxt = 0;
        fs;
        ts;
        s;
        f;
        t;
        ps;
        ps_db;
        delta_power;
        theta_power;
        theta_delta_ratio;
        s_emg;
        f_emg;
        t_emg;
        ps_emg;
        EMG_power;
        mean_EMG_power;
        wake;
        nrem;
        rem;
        EMG_power_thre;
        delta_thre;
        theta_delta_ratio_thre;
        nrem_color=[1,0.5,0.5];
        rem_color=[0,0,0.8];
        wake_color=0.7*ones(1,3);
        delta_thre_tmp;
        theta_thre_tmp;
        EMG_thre_tmp;
        l_delta;
        l_delta_dist;
        l_theta;
    end
    methods (Access = private)
        function plot_index(app, ax,start_seg, end_seg,color,marker_alpha, marker_range)
            if nargin<5
                marker_alpha=0.3;
                marker_range=[-100,100];
            elseif  nargin<6
                marker_range=[-100,100];
            end
            overlap = app.OverlapEditField.Value;
            
            for i=1:length(start_seg)
                patch(ax,[app.t(start_seg(i))-overlap/2,app.t(end_seg(i))+overlap/2,app.t(end_seg(i))+overlap/2,app.t(start_seg(i))-overlap/2],...
                    [marker_range(1),marker_range(1),marker_range(2),marker_range(2)],color,'facealpha',marker_alpha,'edgecolor','none');
            end
        end
        
        function delta_events(app,src,evt)
            evname = evt.EventName;
            switch(evname)
                case{'ROIMoved'}
                    app.delta_thre_tmp = evt.CurrentPosition(1,1);
                    app.l_delta.Postion(1,1) = app.delta_thre_tmp;
                    app.l_delta.Postion(2,1) = app.delta_thre_tmp;
                    app.l_delta_dist.Postion(1,1) = app.delta_thre_tmp;
                    app.l_delta_dist.Postion(2,1) = app.delta_thre_tmp;
            end
        end
        function theta_events(app,src,evt)
            evname = evt.EventName;
            switch(evname)
                case{'ROIMoved'}
                    app.theta_thre_tmp = evt.CurrentPosition(1,2);
            end
        end
        function EMG_events(app,src,evt)
            evname = evt.EventName;
            switch(evname)
                case{'ROIMoved'}
                    app.EMG_thre_tmp = evt.CurrentPosition(1,2);
            end
        end
        function score(app)
            plot(app.deltaUIAxes, app.t, app.delta_power,'k');
            plot(app.thetaratioUIAxes, app.t, app.theta_delta_ratio,'k');
            plot(app.EMGpowerUIAxes, app.t_emg, app.EMG_power,'k');
            hold(app.EMGpowerUIAxes,'on');
            hold(app.deltaUIAxes,'on');
            hold(app.thetaratioUIAxes,'on');
            
            app.wake = find(app.EMG_power > app.EMG_power_thre); 
            app.nrem = setdiff(find(app.delta_power > app.delta_thre), app.wake); 
            app.rem = setdiff(find(app.theta_delta_ratio> app.theta_delta_ratio_thre), [app.wake,app.nrem]);
            app.wake = setdiff(1:length(app.EMG_power),[app.nrem,app.rem]);
                        
            [start_nrem,end_nrem,lens_nrem]=find_bound(app.nrem);
            start_nrem=app.nrem(start_nrem);
            end_nrem=app.nrem(end_nrem);
            [start_rem,end_rem,lens_rem]=find_bound(app.rem);
            start_rem=app.rem(start_rem);
            end_rem=app.rem(end_rem);
            [start_wake,end_wake,lens_wake]=find_bound(app.wake);
            start_wake=app.wake(start_wake);
            end_wake=app.wake(end_wake);
            
            marker_alpha=0.3;
            
            marker_range=[min(app.EMG_power),max(app.EMG_power)];
            plot_index(app, app.EMGpowerUIAxes,start_nrem, end_nrem,app.nrem_color,marker_alpha, marker_range)
            plot_index(app, app.EMGpowerUIAxes,start_rem, end_rem,app.rem_color,marker_alpha, marker_range)
            plot_index(app, app.EMGpowerUIAxes,start_wake, end_wake,app.wake_color,marker_alpha, marker_range)
            
            marker_range=[min(app.delta_power),max(app.delta_power)];
            plot_index(app, app.deltaUIAxes,start_nrem, end_nrem,app.nrem_color,marker_alpha, marker_range)
            plot_index(app, app.deltaUIAxes,start_rem, end_rem,app.rem_color,marker_alpha, marker_range)
            plot_index(app, app.deltaUIAxes,start_wake, end_wake,app.wake_color,marker_alpha, marker_range)
            
            marker_range=[min(app.theta_delta_ratio),max(app.theta_delta_ratio)];
            plot_index(app, app.thetaratioUIAxes,start_nrem, end_nrem,app.nrem_color,marker_alpha, marker_range)
            plot_index(app, app.thetaratioUIAxes,start_rem, end_rem,app.rem_color,marker_alpha, marker_range)
            plot_index(app, app.thetaratioUIAxes,start_wake, end_wake,app.wake_color,marker_alpha, marker_range)
            
            dt = app.t(2)-app.t(1);
            total_len = length(app.EMG_power);
            
            EMG_above = find(app.EMG_power > app.EMG_power_thre);
            EMG_below = setdiff(1:total_len,EMG_above);
            plot(app.EMGpowerUIAxes, app.t_emg(EMG_above),app.EMG_power(EMG_above),'r.');
            plot(app.EMGpowerUIAxes, app.t_emg(EMG_below),app.EMG_power(EMG_below),'b.');
            delta_above = find(app.delta_power > app.delta_thre);
            delta_below = setdiff(1:total_len,delta_above);
            plot(app.deltaUIAxes, app.t(delta_above),app.delta_power(delta_above),'r.');
            plot(app.deltaUIAxes, app.t(delta_below),app.delta_power(delta_below),'b.');
            theta_ratio_above = find(app.theta_delta_ratio > app.theta_delta_ratio_thre);
            theta_ratio_below = setdiff(1:total_len,theta_ratio_above);
            plot(app.thetaratioUIAxes, app.t(theta_ratio_above),app.theta_delta_ratio(theta_ratio_above),'r.');
            plot(app.thetaratioUIAxes, app.t(theta_ratio_below),app.theta_delta_ratio(theta_ratio_below),'b.');
            
            hold(app.EMGpowerUIAxes,'off');
            hold(app.deltaUIAxes,'off');
            hold(app.thetaratioUIAxes,'off');
            
            app.nremLabel.Visible ='on';
            app.nremLabel.Text=['NREM: ',num2str(length(app.nrem)*dt),' s (',num2str(length(app.nrem)/total_len*100,2),' %)'];
            app.nremLabel.FontColor = app.nrem_color;
            
            app.remLabel.Visible ='on';
            app.remLabel.Text=['REM: ',num2str(length(app.rem)*dt),' s (',num2str(length(app.rem)/total_len*100,2),' %)'];
            app.remLabel.FontColor = app.rem_color;
            
            app.wakeLabel.Visible ='on';
            app.wakeLabel.Text=['WAKE: ',num2str(length(app.wake)*dt),' s (',num2str(length(app.wake)/total_len*100,2),' %)'];
            app.wakeLabel.FontColor = app.wake_color;   
            
            ax1 = app.DeltaThetaUIAxes;
            cla(ax1);
            hold(ax1,"on")
            plot(ax1, app.delta_power(app.nrem), app.theta_delta_ratio(app.nrem),'.','Color',app.nrem_color);
            plot(ax1, app.delta_power(app.rem), app.theta_delta_ratio(app.rem),'.','Color',app.rem_color);
            plot(ax1, app.delta_power(app.wake), app.theta_delta_ratio(app.wake),'.','Color',app.wake_color);
            %plot(ax1, app.delta_thre*ones(1,2),[min(app.theta_delta_ratio),max(app.theta_delta_ratio)],'r');
            %plot(ax1, [min(app.delta_power),max(app.delta_power)], app.theta_delta_ratio_thre*ones(1,2),'r');
            app.l_delta = drawline(ax1,'Position',[app.delta_thre, min(app.theta_delta_ratio);app.delta_thre,max(app.theta_delta_ratio)],'Color','r');
            app.l_theta = drawline(ax1,'Position',[min(app.delta_power), app.theta_delta_ratio_thre;max(app.delta_power),app.theta_delta_ratio_thre],'Color','r');
            addlistener(app.l_delta,'ROIMoved',@app.delta_events);
            addlistener(app.l_theta,'ROIMoved',@app.theta_events);
            
            %ylabel(app.ThetaDistUIAxes,'Theta/Delta')
            
            ax12 = app.DeltaDistUIAxes;
            hold(ax12,"on")
            [n_delta,edge_delta]=histcounts(app.delta_power,100);
            dbin = (edge_delta(2)-edge_delta(1))/2;
            bar(ax12,edge_delta(1:end-1)+dbin,n_delta,'FaceColor',0.3*ones(1,3),'EdgeColor','none');
            app.l_delta_dist = drawline(ax12,'Position',[app.delta_thre, 0;app.delta_thre,max(n_delta)],'Color','r');
            addlistener(app.l_delta_dist,'ROIMoved',@app.delta_events);
            
            ax2 = app.EMGThetaUIAxes;
            cla(ax2);
            hold(ax2,"on")
            plot(ax2, app.EMG_power(app.nrem), app.theta_delta_ratio(app.nrem),'.','Color',app.nrem_color);
            plot(ax2, app.EMG_power(app.rem), app.theta_delta_ratio(app.rem),'.','Color',app.rem_color);
            plot(ax2, app.EMG_power(app.wake), app.theta_delta_ratio(app.wake),'.','Color',app.wake_color);
            plot(ax2, app.EMG_power, app.theta_delta_ratio,'.')
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadEEGButton
        function LoadEEGButtonPushed(app, event)
            [file,app.path] = uigetfile('*.continuous','Select EEG data file',app.path);
            if ~isequal(file,0)
                [app.EEG, ~, ~] = load_open_ephys_data(fullfile(app.path,file));
                app.fs=app.fsEditField.Value;
                app.maxt=length(app.EEG)/app.fs;
                app.StarttSlider.Limits(2)=app.maxt;
                app.ts=1/app.fs:1/app.fs:app.maxt;
                plot(app.EEGUIAxes, app.ts, app.EEG,'k');
                start = app.StarttSlider.Value;
                span = app.SpanEditField.Value;
                xlim(app.EEGUIAxes,[start,start+span]);
            end
        end

        % Value changed function: StarttSlider
        function StarttSliderValueChanged(app, event)
            start = app.StarttSlider.Value;
            app.tEditField.Value = start;
            span = app.SpanEditField.Value;
            xlim(app.EEGUIAxes,[start,start+span]);
            xlim(app.EMGUIAxes,[start,start+span]);
            xlim(app.spectrogramUIAxes,[start,start+span]);
            xlim(app.deltaUIAxes,[start,start+span]);
            xlim(app.thetaratioUIAxes,[start,start+span]);
            xlim(app.EMGpowerUIAxes,[start,start+span]);
        end

        % Value changed function: tEditField
        function tEditFieldValueChanged(app, event)
            start = app.tEditField.Value;
            app.StarttSlider.Value = start;
            span = app.SpanEditField.Value;
            xlim(app.EEGUIAxes,[start,start+span]);
            xlim(app.EMGUIAxes,[start,start+span]);
            xlim(app.spectrogramUIAxes,[start,start+span]);
            xlim(app.deltaUIAxes,[start,start+span]);
            xlim(app.thetaratioUIAxes,[start,start+span]);
            xlim(app.EMGpowerUIAxes,[start,start+span]);
        end

        % Button pushed function: LoadEMGButton
        function LoadEMGButtonPushed(app, event)
            [file,app.path] = uigetfile('*.continuous','Select EMG data file',app.path);
            if ~isequal(file,0)
                [app.EMG, ~, ~] = load_open_ephys_data(fullfile(app.path,file));
                app.fs=app.fsEditField.Value;
                app.maxt=length(app.EMG)/app.fs;
                app.StarttSlider.Limits(2)=app.maxt;
                app.ts=1/app.fs:1/app.fs:app.maxt;
                plot(app.EMGUIAxes, app.ts, app.EMG,'k');
                start = app.StarttSlider.Value;
                span = app.SpanEditField.Value;
                xlim(app.EMGUIAxes,[start,start+span]);
            end
        end

        % Button pushed function: FullViewButton
        function FullViewButtonPushed(app, event)
            xlim(app.EEGUIAxes,[0,app.maxt]);
            xlim(app.EMGUIAxes,[0,app.maxt]);
            xlim(app.spectrogramUIAxes,[0,app.maxt]);
            xlim(app.deltaUIAxes,[0,app.maxt]);
            xlim(app.thetaratioUIAxes,[0,app.maxt]);
            xlim(app.EMGpowerUIAxes,[0,app.maxt]);
            app.StarttSlider.Value = 0;
            app.tEditField.Value = 0;
            app.SpanEditField.Value = app.maxt;
        end

        % Button pushed function: EEGSpectrogramButton
        function EEGSpectrogramButtonPushed(app, event)
            %app.fs=app.fsEditField.Value;
            win_len = app.WinLenEditField.Value;
            overlap = app.OverlapEditField.Value;
            [app.s,app.f,app.t,app.ps]=spectrogram(app.EEG,win_len*app.fs,overlap*app.fs,0.5:0.1:20,app.fs);
            app.s=abs(app.s);
            app.ps_db=10*log10(app.ps);
            imagesc(app.spectrogramUIAxes, app.t,app.f,app.ps_db);
            caxis(app.spectrogramUIAxes,[-2,30]);
            colormap(app.spectrogramUIAxes, jet);
            start = app.StarttSlider.Value;
            span = app.SpanEditField.Value;
            xlim(app.spectrogramUIAxes,[start,start+span]);
            
            delta_range=find(app.f>=0.65 & app.f<=4);
            theta_range=find(app.f>=6 & app.f<=12);
            app.delta_power=sum(app.ps(delta_range,:));
            app.theta_power=sum(app.ps(theta_range,:));
            app.theta_delta_ratio=app.theta_power./app.delta_power;
            plot(app.deltaUIAxes, app.t, app.delta_power,'k');
            xlim(app.deltaUIAxes,[start,start+span]);
            plot(app.thetaratioUIAxes, app.t, app.theta_delta_ratio,'k');
            xlim(app.thetaratioUIAxes,[start,start+span]);
            
            [app.s_emg,app.f_emg,app.t_emg,app.ps_emg]=spectrogram(app.EMG,win_len*app.fs,overlap*app.fs,20:300,app.fs,'yaxis');
            app.EMG_power=mean(app.ps_emg,1);
            app.mean_EMG_power=mean(app.EMG_power);
            plot(app.EMGpowerUIAxes, app.t_emg, app.EMG_power,'k');
            xlim(app.EMGpowerUIAxes,[start,start+span]);
            
            ax1 = app.DeltaThetaUIAxes;
            cla(ax1);
            hold(ax1,"on")
            plot(ax1, app.delta_power,app.theta_delta_ratio,'.');
%             plot(ax1, app.delta_power(app.nrem), app.theta_delta_ratio(app.nrem),'.','Color',app.nrem_color);
%             plot(ax1, app.delta_power(app.rem), app.theta_delta_ratio(app.rem),'.','Color',app.rem_color);
%             plot(ax1, app.delta_power(app.wake), app.theta_delta_ratio(app.wake),'.','Color',app.wake_color);
            %plot(ax1, app.delta_thre*ones(1,2),[min(app.theta_delta_ratio),max(app.theta_delta_ratio)],'r');
            %plot(ax1, [min(app.delta_power),max(app.delta_power)], app.theta_delta_ratio_thre*ones(1,2),'r');
            if isempty(app.delta_thre_tmp)
                app.EMG_power_thre = app.mean_EMG_power;
            app.delta_thre_tmp = mean(app.delta_power);
            app.theta_delta_ratio_thre = mean(app.theta_delta_ratio)+std(app.theta_delta_ratio);            
            
            else
                disp('not empty');
            end
%             app.l_delta = drawline(ax1,'Position',[app.delta_thre, min(app.theta_delta_ratio);app.delta_thre,max(app.theta_delta_ratio)],'Color','r');
%             app.l_theta = drawline(ax1,'Position',[min(app.delta_power), app.theta_delta_ratio_thre;max(app.delta_power),app.theta_delta_ratio_thre],'Color','r');
%             addlistener(app.l_delta,'ROIMoved',@app.delta_events);
%             addlistener(app.l_theta,'ROIMoved',@app.theta_events);
            
            %ylabel(app.ThetaDistUIAxes,'Theta/Delta')
            
            ax12 = app.DeltaDistUIAxes;
            hold(ax12,"on")
            [n_delta,edge_delta]=histcounts(app.delta_power,100);
            dbin = (edge_delta(2)-edge_delta(1))/2;
            bar(ax12,edge_delta(1:end-1)+dbin,n_delta,'FaceColor',0.3*ones(1,3),'EdgeColor','none');
%             app.l_delta_dist = drawline(ax12,'Position',[app.delta_thre, 0;app.delta_thre,max(n_delta)],'Color','r');
%             addlistener(app.l_delta_dist,'ROIMoved',@app.delta_events);
            
            ax2 = app.EMGThetaUIAxes;
            cla(ax2);
            hold(ax2,"on")
%             plot(ax2, app.EMG_power(app.nrem), app.theta_delta_ratio(app.nrem),'.','Color',app.nrem_color);
%             plot(ax2, app.EMG_power(app.rem), app.theta_delta_ratio(app.rem),'.','Color',app.rem_color);
%             plot(ax2, app.EMG_power(app.wake), app.theta_delta_ratio(app.wake),'.','Color',app.wake_color);
            plot(ax2, app.EMG_power, app.theta_delta_ratio,'.')
        end

        % Value changed function: SpanEditField
        function SpanEditFieldValueChanged(app, event)
            span = app.SpanEditField.Value;
            start = app.tEditField.Value;
            xlim(app.EEGUIAxes,[start,start+span]);
            xlim(app.EMGUIAxes,[start,start+span]);
            xlim(app.spectrogramUIAxes,[start,start+span]);
            xlim(app.deltaUIAxes,[start,start+span]);
            xlim(app.thetaratioUIAxes,[start,start+span]);
            xlim(app.EMGpowerUIAxes,[start,start+span]);
        end

        % Value changed function: fsEditField
        function fsEditFieldValueChanged(app, event)
            app.fs = app.fsEditField.Value;
        end

        % Button down function: deltaUIAxes
        function deltaUIAxesButtonDown(app, event)
            if app.SelROICheckBox.Value == 1
                p = app.deltaUIAxes.CurrentPoint;
                startx = floor(p(1,1)/2.5)*2.5;
                w = app.EpochEditField.Value;
                h = app.deltaUIAxes.YLim(2)-app.deltaUIAxes.YLim(1);
                % rect = [startx, 0, app.EpochEditField.Value, app.deltaUIAxes.YLim(2)-app.deltaUIAxes.YLim(1)];
                % h = images.roi.Rectangle(app.deltaUIAxes,'Position',rect,'Color','r');
                patch(app.deltaUIAxes,[startx,startx+w,startx+w,startx],[0,0,h,h],'r','alpha',0.1);
            end
        end

        % Button pushed function: AutoScoreButton
        function AutoScoreButtonPushed(app, event)
            app.EMG_power_thre = app.mean_EMG_power;
            app.delta_thre = mean(app.delta_power);
            app.theta_delta_ratio_thre = mean(app.theta_delta_ratio)+std(app.theta_delta_ratio);            
            score(app);
        end

        % Button pushed function: ManualThresholdButton
        function ManualThresholdButtonPushed(app, event)
            app.EMG_power_thre = app.EMG_power_thre_tmp;
            app.delta_thre = app.delta_thre_tmp;
            app.theta_delta_ratio_thre = app.theta_thre_tmp;
            score(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadEEGButton = uibutton(app.UIFigure, 'push');
            app.LoadEEGButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEEGButtonPushed, true);
            app.LoadEEGButton.Text = 'Load EEG';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.StarttSliderLabel = uilabel(app.UIFigure);
            app.StarttSliderLabel.Text = 'Start t';
            app.StarttSlider = uislider(app.UIFigure);
            app.StarttSlider.ValueChangedFcn = createCallbackFcn(app, @StarttSliderValueChanged, true);
            app.fsEditFieldLabel = uilabel(app.UIFigure);
            app.fsEditFieldLabel.Text = 'fs';
            app.SpanEditFieldLabel = uilabel(app.UIFigure);
            app.SpanEditFieldLabel.Text = 'Span';
            app.SpanEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpanEditField.ValueChangedFcn = createCallbackFcn(app, @SpanEditFieldValueChanged, true);
            app.SpanEditField.Value = 60;
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.fsEditField = uieditfield(app.UIFigure, 'numeric');
            app.fsEditField.ValueChangedFcn = createCallbackFcn(app, @fsEditFieldValueChanged, true);
            app.fsEditField.Value = 1000;
            app.tEditFieldLabel = uilabel(app.UIFigure);
            app.tEditFieldLabel.Text = 't';
            app.tEditField = uieditfield(app.UIFigure, 'numeric');
            app.tEditField.ValueChangedFcn = createCallbackFcn(app, @tEditFieldValueChanged, true);
            app.LoadEMGButton = uibutton(app.UIFigure, 'push');
            app.LoadEMGButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEMGButtonPushed, true);
            app.LoadEMGButton.Text = 'Load EMG';
            app.FullViewButton = uibutton(app.UIFigure, 'push');
            app.FullViewButton.ButtonPushedFcn = createCallbackFcn(app, @FullViewButtonPushed, true);
            app.FullViewButton.Text = 'Full View';
            app.EEGSpectrogramButton = uibutton(app.UIFigure, 'push');
            app.EEGSpectrogramButton.ButtonPushedFcn = createCallbackFcn(app, @EEGSpectrogramButtonPushed, true);
            app.EEGSpectrogramButton.Text = 'EEG Spectrogram';
            app.WinLenEditFieldLabel = uilabel(app.UIFigure);
            app.WinLenEditFieldLabel.Text = 'Win Len';
            app.WinLenEditField = uieditfield(app.UIFigure, 'numeric');
            app.WinLenEditField.Limits = [0 Inf];
            app.WinLenEditField.Value = 5;
            app.sLabel_3 = uilabel(app.UIFigure);
            app.sLabel_3.Text = 's';
            app.OverlapEditFieldLabel = uilabel(app.UIFigure);
            app.OverlapEditFieldLabel.Text = 'Overlap';
            app.OverlapEditField = uieditfield(app.UIFigure, 'numeric');
            app.OverlapEditField.Limits = [0 Inf];
            app.OverlapEditField.Value = 2.5;
            app.sLabel_4 = uilabel(app.UIFigure);
            app.sLabel_4.Text = 's';
            app.sLabel_2 = uilabel(app.UIFigure);
            app.sLabel_2.Text = 's';
            app.SelROICheckBox = uicheckbox(app.UIFigure);
            app.SelROICheckBox.Text = 'Sel ROI';
            app.EpochEditFieldLabel = uilabel(app.UIFigure);
            app.EpochEditFieldLabel.Text = 'Epoch';
            app.EpochEditField = uieditfield(app.UIFigure, 'numeric');
            app.EpochEditField.Value = 4;
            app.sLabel_5 = uilabel(app.UIFigure);
            app.sLabel_5.Text = 's';
            app.AutoScoreButton = uibutton(app.UIFigure, 'push');
            app.AutoScoreButton.ButtonPushedFcn = createCallbackFcn(app, @AutoScoreButtonPushed, true);
            app.AutoScoreButton.Text = 'Auto Score';
            app.ManualThresholdButton = uibutton(app.UIFigure, 'push');
            app.ManualThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ManualThresholdButtonPushed, true);
            app.ManualThresholdButton.Text = 'Manual Threshold';
            app.nremLabel = uilabel(app.UIFigure);
            app.nremLabel.Text = ' ';
            app.remLabel = uilabel(app.UIFigure);
            app.remLabel.Text = ' ';
            app.wakeLabel = uilabel(app.UIFigure);
            app.wakeLabel.Text = ' ';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EEGUIAxes = uiaxes(app.UIFigure);
            title(app.EEGUIAxes, 'EEG')
            xlabel(app.EEGUIAxes, 'Time(s)')
            ylabel(app.EEGUIAxes, 'uV')
            zlabel(app.EEGUIAxes, 'Z')
            app.EMGUIAxes = uiaxes(app.UIFigure);
            title(app.EMGUIAxes, 'EMG')
            xlabel(app.EMGUIAxes, 'Time(s)')
            ylabel(app.EMGUIAxes, 'uV')
            zlabel(app.EMGUIAxes, 'Z')
            app.spectrogramUIAxes = uiaxes(app.UIFigure);
            title(app.spectrogramUIAxes, 'Spectrogram')
            xlabel(app.spectrogramUIAxes, 'Time(s)')
            ylabel(app.spectrogramUIAxes, 'dB')
            zlabel(app.spectrogramUIAxes, 'Z')
            app.deltaUIAxes = uiaxes(app.UIFigure);
            title(app.deltaUIAxes, 'Delta Power')
            xlabel(app.deltaUIAxes, 'Time(s)')
            ylabel(app.deltaUIAxes, 'uV^2')
            zlabel(app.deltaUIAxes, 'Z')
            app.deltaUIAxes.ButtonDownFcn = createCallbackFcn(app, @deltaUIAxesButtonDown, true);
            app.thetaratioUIAxes = uiaxes(app.UIFigure);
            title(app.thetaratioUIAxes, 'Theta/Delta')
            xlabel(app.thetaratioUIAxes, 'Time(s)')
            ylabel(app.thetaratioUIAxes, 'ratio')
            zlabel(app.thetaratioUIAxes, 'Z')
            app.EMGpowerUIAxes = uiaxes(app.UIFigure);
            title(app.EMGpowerUIAxes, 'EMG Power')
            xlabel(app.EMGpowerUIAxes, 'Time (s)')
            ylabel(app.EMGpowerUIAxes, 'uV^2')
            zlabel(app.EMGpowerUIAxes, 'Z')
            app.DeltaThetaUIAxes = uiaxes(app.UIFigure);
            zlabel(app.DeltaThetaUIAxes, 'Z')
            app.ThetaDistUIAxes = uiaxes(app.UIFigure);
            ylabel(app.ThetaDistUIAxes, 'Theta/Delta')
            zlabel(app.ThetaDistUIAxes, 'Z')
            app.DeltaDistUIAxes = uiaxes(app.UIFigure);
            xlabel(app.DeltaDistUIAxes, 'Delta Power')
            zlabel(app.DeltaDistUIAxes, 'Z')
            app.EMGThetaUIAxes = uiaxes(app.UIFigure);
            zlabel(app.EMGThetaUIAxes, 'Z')
            app.ThetaDistUIAxes2 = uiaxes(app.UIFigure);
            ylabel(app.ThetaDistUIAxes2, 'Theta/Delta')
            zlabel(app.ThetaDistUIAxes2, 'Z')
            app.EMGpowerUIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.EMGpowerUIAxes2, 'EMG Power')
            zlabel(app.EMGpowerUIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sleep_ana

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end